classdef an_num2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NeuronSimulatorUIFigure         matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        NeuronalcableTab_2              matlab.ui.container.Tab
        PlayonyourneuronsLabel          matlab.ui.control.Label
        ResponseofsinglepassivecablePanel  matlab.ui.container.Panel
        TwocurrentinjectionButton       matlab.ui.control.Button
        SynapticinputButton             matlab.ui.control.Button
        TwosynapticinputButton          matlab.ui.control.Button
        ResponseofactivecablestocurrentinjectionPanel  matlab.ui.container.Panel
        SinglecableButton               matlab.ui.control.Button
        TwocablesButton                 matlab.ui.control.Button
        NonuniformsinglecableButton     matlab.ui.control.Button
        AndifyouwannaknowmoreaboutLabel  matlab.ui.control.Label
        JUSTHITONMELabel                matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        ElectrotonicsignatureTab        matlab.ui.container.Tab
        SimulateyourdendritictreeLabel  matlab.ui.control.Label
        SteadystateresponsetoastimilusPanel  matlab.ui.container.Panel
        singletreeButton                matlab.ui.control.Button
        elsynconnectedtreeButton        matlab.ui.control.Button
        SteadystateresponsetoasynapsePanel  matlab.ui.container.Panel
        singletreeButton_2              matlab.ui.control.Button
        elsynconnectedtreeButton_2      matlab.ui.control.Button
        StimilusEditFieldLabel          matlab.ui.control.Label
        StimilusEditField               matlab.ui.control.NumericEditField
        InhibitorysynapticinputEditFieldLabel  matlab.ui.control.Label
        InhibitorysynapticinputEditField  matlab.ui.control.NumericEditField
        ExcitatorySynapticinputEditFieldLabel  matlab.ui.control.Label
        ExcitatorySynapticinputEditField  matlab.ui.control.NumericEditField
        Button_3                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        Button_5                        matlab.ui.control.Button
        NeuronalnetworkTab              matlab.ui.container.Tab
        Button_6                        matlab.ui.control.Button
        LeakyintegrateandfirecellmodelLabel  matlab.ui.control.Label
        SimpletwocellnetworkLabel       matlab.ui.control.Label
        Button_7                        matlab.ui.control.Button
        SimplethreecellnetworkLabel     matlab.ui.control.Label
        Button_8                        matlab.ui.control.Button
        HodgkinHuxleybasednetworksLabel  matlab.ui.control.Label
        CurrentSliderLabel              matlab.ui.control.Label
        CurrentSlider                   matlab.ui.control.Slider
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        ElectrotonicofnervecellsLabel   matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: TwocurrentinjectionButton
        function TwocurrentinjectionButtonPushed(app, event)
            cab = struct('rad',1e-4,'ell',.1,'dx',1e-3,'dt',0.05);
              stim = struct('t1',1,'t2',2,'amp',1e-4,'loc',0.06,'Tfin',10);
               pinc = 1;
                 trapcab(cab,stim,pinc)
                 roundshow;
        end

        % Button pushed function: SynapticinputButton
        function SynapticinputButtonPushed(app, event)
            cab = struct('rad',1e-4,'ell',.1,'dx',1e-3,'dt',0.05);
             stim = struct('t1',1,'tau',1,'Gsyn',1e-4,'loc',0.05,'Tfin',10);
             pinc = 1;
             trapcabsyn(cab,stim,pinc)
             roundshow;
        end

        % Button pushed function: TwosynapticinputButton
        function TwosynapticinputButtonPushed(app, event)
             cab = struct('rad',1e-4,'ell',1e-1,'dx',1e-3,'dt',0.05);
             stim = struct('t1',1,'tau',1,'Gsyn',100,'loc',0.05,'Tfin',10);
              pinc = 1 ;
              trapcabspine(cab,stim,pinc);
              roundshow;
        end

        % Button pushed function: SinglecableButton
        function SinglecableButtonPushed(app, event)
             stim = struct('t1',1,'t2',2,'amp',4e-4,'loc',0.05,'Tfin',8);
             pinc = 1 ;
               cab = struct('rad',1e-4,'ell',1e-1,'dx',1e-4,'dt',0.02);
              x = cab.dx/2:cab.dx:cab.ell-cab.dx/2;
              hot = ((x>0.005).*(x<0.01))';
             g = struct('K', 40-20*hot, 'Na', 44+560*hot, 'Cl', 1/15);
           
           stEcab(cab,g,stim,pinc);
           roundshow;
        end

        % Button pushed function: TwocablesButton
        function TwocablesButtonPushed(app, event)
          cab = struct('rad',[1e-4 1e-4 5e-5],'ell',1e-1,'dx',1e-4,'dt',0.02);
          stim = struct('t1',1,'t2',2,'amp',[4e-4 0],'loc',0.03,'Tfin',8);
          pinc = 10;
          stE2cab(cab,stim,pinc);
        

        end

        % Button pushed function: NonuniformsinglecableButton
        function NonuniformsinglecableButtonPushed(app, event)
             stim = struct('t1',1,'t2',2,'amp',4e-4,'loc',0.05,'Tfin',8);
             pinc = 1;
             cab = struct('rad',1e-4,'ell',1e-1,'dx',1e-4,'dt',0.02);
              stEcabnon(cab,stim,pinc);
              roundshow;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            stim = struct('t1',1,'t2',2,'amp',1e-6,'Tfin',8);
             pinc = 1;
             trapforkd(0.01,stim,pinc)
        end

        % Button pushed function: singletreeButton
        function singletreeButtonPushed(app, event)
            sse_tree (sample_tree, app.StimilusEditField.Value, '-s');
            roundshow;
        end

        % Button pushed function: elsynconnectedtreeButton
        function elsynconnectedtreeButtonPushed(app, event)
            ssecat_tree ({sample_tree, tran_tree(sample2_tree,[-50 30 0])}, 197,...
205, .01, 195, '-s');
roundshow;
        end

        % Button pushed function: singletreeButton_2
        function singletreeButton_2Pushed(app, event)
            
             
             syn_tree (hss_tree, app.InhibitorysynapticinputEditField.Value, app.ExcitatorySynapticinputEditField.Value, [], [], [], '-s');
             roundshow;
        end

        % Button pushed function: elsynconnectedtreeButton_2
        function elsynconnectedtreeButton_2Pushed(app, event)
            syncat_tree ({sample_tree, tran_tree(sample2_tree,[-50 30 0])}, 197,...
                205, .01, app.InhibitorysynapticinputEditField.Value, app.ExcitatorySynapticinputEditField.Value, [], [], [], '-s');
            roundshow;
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
          
           app.UIAxes_3.Visible='On';
           app.UIAxes_4.Visible='On';
           app.UIAxes_5.Visible='On';
           app.UIAxes.Visible='On';
            [t,gE1,V1,gE2,V2]=twocell(0.01,50,5);
            curve1=animatedline(app.UIAxes,'Color','m','LineWidth',2);
            set(gca,'xtick',[],'tickdir','out')
            ylabel(app.UIAxes,'g_{E,1}','fontsize',14)
             curve2=animatedline(app.UIAxes_3,'Color','g','LineWidth',2);
            set(gca,'xtick',[],'tickdir','out')
            ylabel(app.UIAxes_3,'V_1','fontsize',14)
            curve3=animatedline(app.UIAxes_4,'Color','m','LineWidth',2);


ylabel(app.UIAxes_4,'g_{E,2}','fontsize',14)
set(gca,'xtick',[],'tickdir','out')

curve4=animatedline(app.UIAxes_5,'Color','g','LineWidth',2);


set(gca,'tickdir','out')
xlabel(app.UIAxes_5,'t (ms)','fontsize',14)
ylabel(app.UIAxes_5,'V_2','fontsize',14)
for i=1:length(t)
addpoints(curve1,t(i),gE1(i));
addpoints(curve2,t(i),V1(i));
addpoints(curve3,t(i),gE2(i));
addpoints(curve4,t(i),V2(i));
drawnow limitrate

end
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
             cla( app.UIAxes_3)
            cla( app.UIAxes)
           cla( app.UIAxes_4)
           cla( app.UIAxes_5)  
             app.UIAxes_3.Visible='Off';
           app.UIAxes_4.Visible='On';
           app.UIAxes_5.Visible='On';
           app.UIAxes.Visible='On';
           [t,gE,V]=threecell(0.01,50,5);
            plot(app.UIAxes_4,t,V(1,:),'m','LineWidth',2);
            ylabel(app.UIAxes_4,'V_1','fontsize',14);
             plot(app.UIAxes,t,V(2,:),'g','LineWidth',2);
            ylabel(app.UIAxes,'V_2','fontsize',14);
             plot(app.UIAxes_5,t,V(3,:),'LineWidth',2);
            ylabel(app.UIAxes_5,'V_3','fontsize',14);
            
        end

        % Value changing function: CurrentSlider
        function CurrentSliderValueChanging(app, event)
            
            
           cla( app.UIAxes)
           cla( app.UIAxes_4)
           cla( app.UIAxes_5) 
           cla( app.UIAxes_3)
             app.UIAxes_3.Visible='On';
           app.UIAxes_4.Visible='Off';
           app.UIAxes_5.Visible='Off';
           app.UIAxes.Visible='Off';


               IsA = event.Value;
              Tfin=8;
            dt=0.001;
            [t,V]=ml2(dt, Tfin, IsA);
          
            curve1=animatedline(app.UIAxes_3,'Color','m','LineWidth',2);
            curve2=animatedline(app.UIAxes_3,'Color','m','LineWidth',2);
            curve3=animatedline(app.UIAxes_3,'Color','g','LineWidth',2);
            curve4=animatedline(app.UIAxes_3,'Color','g','LineWidth',2);
          
            
             set(gca,'xtick',[],'tickdir','out')
             text(app.UIAxes_3,Tfin-1,20,'V_1')
             text(app.UIAxes_3,Tfin-1,20-180,'V_2')
            for i=1:length(t)


            addpoints(curve3,t(i),0);
            addpoints(curve4,t(i),-140);
            addpoints(curve1,t(i),V(i,1));
           addpoints(curve2,t(i),(V(i,2)-140));
             drawnow limitrate

            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NeuronSimulatorUIFigure = uifigure;
            app.NeuronSimulatorUIFigure.Name = 'Neuron Simulator';
            app.TabGroup = uitabgroup(app.NeuronSimulatorUIFigure);
            app.NeuronalcableTab_2 = uitab(app.TabGroup);
            app.NeuronalcableTab_2.Title = 'Neuronal cable';
            app.PlayonyourneuronsLabel = uilabel(app.NeuronalcableTab_2);
            app.PlayonyourneuronsLabel.Text = 'Play on your neurons';
            app.ResponseofsinglepassivecablePanel = uipanel(app.NeuronalcableTab_2);
            app.ResponseofsinglepassivecablePanel.Title = 'Response of single passive cable';
            app.TwocurrentinjectionButton = uibutton(app.ResponseofsinglepassivecablePanel, 'push');
            app.TwocurrentinjectionButton.ButtonPushedFcn = createCallbackFcn(app, @TwocurrentinjectionButtonPushed, true);
            app.TwocurrentinjectionButton.Text = 'Two current injection';
            app.SynapticinputButton = uibutton(app.ResponseofsinglepassivecablePanel, 'push');
            app.SynapticinputButton.ButtonPushedFcn = createCallbackFcn(app, @SynapticinputButtonPushed, true);
            app.SynapticinputButton.Text = 'Synaptic input';
            app.TwosynapticinputButton = uibutton(app.ResponseofsinglepassivecablePanel, 'push');
            app.TwosynapticinputButton.ButtonPushedFcn = createCallbackFcn(app, @TwosynapticinputButtonPushed, true);
            app.TwosynapticinputButton.Text = 'Two synaptic input';
            app.ResponseofactivecablestocurrentinjectionPanel = uipanel(app.NeuronalcableTab_2);
            app.ResponseofactivecablestocurrentinjectionPanel.Title = 'Response of active cables to current injection   ';
            app.SinglecableButton = uibutton(app.ResponseofactivecablestocurrentinjectionPanel, 'push');
            app.SinglecableButton.ButtonPushedFcn = createCallbackFcn(app, @SinglecableButtonPushed, true);
            app.SinglecableButton.Text = 'Single cable';
            app.TwocablesButton = uibutton(app.ResponseofactivecablestocurrentinjectionPanel, 'push');
            app.TwocablesButton.ButtonPushedFcn = createCallbackFcn(app, @TwocablesButtonPushed, true);
            app.TwocablesButton.Text = 'Two cables';
            app.NonuniformsinglecableButton = uibutton(app.ResponseofactivecablestocurrentinjectionPanel, 'push');
            app.NonuniformsinglecableButton.ButtonPushedFcn = createCallbackFcn(app, @NonuniformsinglecableButtonPushed, true);
            app.NonuniformsinglecableButton.Text = 'Nonuniform single cable';
            app.AndifyouwannaknowmoreaboutLabel = uilabel(app.NeuronalcableTab_2);
            app.AndifyouwannaknowmoreaboutLabel.Text = 'And if you wanna know more about how distributed current input does not reach the soma in passive dendritic tree';
            app.JUSTHITONMELabel = uilabel(app.NeuronalcableTab_2);
            app.JUSTHITONMELabel.Text = 'JUST HIT ON ME';
            app.Button = uibutton(app.NeuronalcableTab_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'neuron-clipart.jpg';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.NeuronalcableTab_2, 'push');
            app.Button_2.Icon = 'back.jpg';
            app.Button_2.Text = '';
            app.ElectrotonicsignatureTab = uitab(app.TabGroup);
            app.ElectrotonicsignatureTab.Title = 'Electrotonic signature';
            app.SimulateyourdendritictreeLabel = uilabel(app.ElectrotonicsignatureTab);
            app.SimulateyourdendritictreeLabel.Text = 'Simulate your dendritic tree';
            app.SteadystateresponsetoastimilusPanel = uipanel(app.ElectrotonicsignatureTab);
            app.SteadystateresponsetoastimilusPanel.Title = 'Steady state response to a stimilus';
            app.singletreeButton = uibutton(app.SteadystateresponsetoastimilusPanel, 'push');
            app.singletreeButton.ButtonPushedFcn = createCallbackFcn(app, @singletreeButtonPushed, true);
            app.singletreeButton.Text = 'single tree';
            app.elsynconnectedtreeButton = uibutton(app.SteadystateresponsetoastimilusPanel, 'push');
            app.elsynconnectedtreeButton.ButtonPushedFcn = createCallbackFcn(app, @elsynconnectedtreeButtonPushed, true);
            app.elsynconnectedtreeButton.Text = 'el-syn connected tree';
            app.SteadystateresponsetoasynapsePanel = uipanel(app.ElectrotonicsignatureTab);
            app.SteadystateresponsetoasynapsePanel.Title = 'Steady state response to a synapse';
            app.singletreeButton_2 = uibutton(app.SteadystateresponsetoasynapsePanel, 'push');
            app.singletreeButton_2.ButtonPushedFcn = createCallbackFcn(app, @singletreeButton_2Pushed, true);
            app.singletreeButton_2.Text = 'single tree';
            app.elsynconnectedtreeButton_2 = uibutton(app.SteadystateresponsetoasynapsePanel, 'push');
            app.elsynconnectedtreeButton_2.ButtonPushedFcn = createCallbackFcn(app, @elsynconnectedtreeButton_2Pushed, true);
            app.elsynconnectedtreeButton_2.Text = 'el-syn connected tree';
            app.StimilusEditFieldLabel = uilabel(app.ElectrotonicsignatureTab);
            app.StimilusEditFieldLabel.Text = 'Stimilus';
            app.StimilusEditField = uieditfield(app.ElectrotonicsignatureTab, 'numeric');
            app.StimilusEditField.Value = 11;
            app.InhibitorysynapticinputEditFieldLabel = uilabel(app.ElectrotonicsignatureTab);
            app.InhibitorysynapticinputEditFieldLabel.Text = 'Inhibitory synaptic input';
            app.InhibitorysynapticinputEditField = uieditfield(app.ElectrotonicsignatureTab, 'numeric');
            app.InhibitorysynapticinputEditField.Limits = [10 400];
            app.InhibitorysynapticinputEditField.Value = 100;
            app.ExcitatorySynapticinputEditFieldLabel = uilabel(app.ElectrotonicsignatureTab);
            app.ExcitatorySynapticinputEditFieldLabel.Text = 'Excitatory Synaptic input';
            app.ExcitatorySynapticinputEditField = uieditfield(app.ElectrotonicsignatureTab, 'numeric');
            app.ExcitatorySynapticinputEditField.Limits = [10 400];
            app.ExcitatorySynapticinputEditField.Value = 100;
            app.Button_3 = uibutton(app.ElectrotonicsignatureTab, 'push');
            app.Button_3.Icon = 'Axodendritic3.jpg';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.ElectrotonicsignatureTab, 'push');
            app.Button_4.Icon = 'messages.jpg';
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.ElectrotonicsignatureTab, 'push');
            app.Button_5.Icon = 'Mirror-neu-sml.jpg';
            app.Button_5.Text = '';
            app.NeuronalnetworkTab = uitab(app.TabGroup);
            app.NeuronalnetworkTab.Title = 'Neuronal network';
            app.Button_6 = uibutton(app.NeuronalnetworkTab, 'push');
            app.Button_6.Icon = 'neural networking.jpg';
            app.Button_6.Text = '';
            app.LeakyintegrateandfirecellmodelLabel = uilabel(app.NeuronalnetworkTab);
            app.LeakyintegrateandfirecellmodelLabel.Text = 'Leaky integrate and fire cell model';
            app.SimpletwocellnetworkLabel = uilabel(app.NeuronalnetworkTab);
            app.SimpletwocellnetworkLabel.Text = 'Simple two-cell network';
            app.Button_7 = uibutton(app.NeuronalnetworkTab, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Icon = 'neurons.PNG';
            app.Button_7.Text = '';
            app.SimplethreecellnetworkLabel = uilabel(app.NeuronalnetworkTab);
            app.SimplethreecellnetworkLabel.Text = 'Simple three-cell network';
            app.Button_8 = uibutton(app.NeuronalnetworkTab, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Icon = '3 cells.jpg';
            app.Button_8.Text = '';
            app.HodgkinHuxleybasednetworksLabel = uilabel(app.NeuronalnetworkTab);
            app.HodgkinHuxleybasednetworksLabel.Text = 'Hodgkin Huxley based networks';
            app.CurrentSliderLabel = uilabel(app.NeuronalnetworkTab);
            app.CurrentSliderLabel.Text = 'Current';
            app.CurrentSlider = uislider(app.NeuronalnetworkTab);
            app.CurrentSlider.Limits = [1 3100];
            app.CurrentSlider.ValueChangingFcn = createCallbackFcn(app, @CurrentSliderValueChanging, true);
            app.CurrentSlider.Value = 2000;
            app.UIAxes = uiaxes(app.NeuronalnetworkTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_3 = uiaxes(app.NeuronalnetworkTab);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_4 = uiaxes(app.NeuronalnetworkTab);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.UIAxes_5 = uiaxes(app.NeuronalnetworkTab);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            app.ElectrotonicofnervecellsLabel = uilabel(app.NeuronSimulatorUIFigure);
            app.ElectrotonicofnervecellsLabel.Text = 'Electrotonic of nerve cells';
        end
    end

    methods (Access = public)

        % Construct app
        function app = an_num2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NeuronSimulatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NeuronSimulatorUIFigure)
        end
    end
end