classdef num_an < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NeuronSimulatorUIFigure        matlab.ui.Figure
        ElectrotonicofnervecellsLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        ThepassiveisopotentialcellTab  matlab.ui.container.Tab
        ListBox                        matlab.ui.control.ListBox
        Button                         matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        CommentsTextAreaLabel          matlab.ui.control.Label
        CommentsTextArea               matlab.ui.control.TextArea
        PeriodofsynapticinputPanel     matlab.ui.container.Panel
        msButton                       matlab.ui.control.Button
        msButton_2                     matlab.ui.control.Button
        PeriodofinjectionSliderLabel   matlab.ui.control.Label
        PeriodofinjectionSlider        matlab.ui.control.Slider
        CurrentinjectionSliderLabel    matlab.ui.control.Label
        CurrentinjectionSlider         matlab.ui.control.Slider
        UIAxes_2                       matlab.ui.control.UIAxes
        Button_3                       matlab.ui.control.Button
        TheactiveisopotentialcellTab   matlab.ui.container.Tab
        ListBox_2                      matlab.ui.control.ListBox
        Button_4                       matlab.ui.control.Button
        Button_5                       matlab.ui.control.Button
        PeriodofinjectionmsKnobLabel   matlab.ui.control.Label
        PeriodofinjectionmsKnob        matlab.ui.control.Knob
        IstimiluspALabel               matlab.ui.control.Label
        IstimiluspAKnob                matlab.ui.control.Knob
        InputButtonGroup               matlab.ui.container.ButtonGroup
        CurrentinjectionButton         matlab.ui.control.RadioButton
        SynapseButton                  matlab.ui.control.RadioButton
        manipulateSwitchLabel          matlab.ui.control.Label
        manipulateSwitch               matlab.ui.control.Switch
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes3_2                      matlab.ui.control.UIAxes
        UIAxes3_3                      matlab.ui.control.UIAxes
        NeuronalnetworksTab            matlab.ui.container.Tab
        Button_6                       matlab.ui.control.Button
        SimpletwocellnetworkLabel      matlab.ui.control.Label
        LeakyintegrateandfirecellmodelLabel  matlab.ui.control.Label
        Button_7                       matlab.ui.control.Button
        SimplethreecellnetworkLabel    matlab.ui.control.Label
        PeriodButtonGroup              matlab.ui.container.ButtonGroup
        msButton_3                     matlab.ui.control.RadioButton
        msButton_4                     matlab.ui.control.RadioButton
        HodgkinHuxleybasednetworksLabel  matlab.ui.control.Label
        currentSliderLabel             matlab.ui.control.Label
        currentSlider                  matlab.ui.control.Slider
        networkofPinskyRinzelcellsLabel  matlab.ui.control.Label
        cellburstfractionButton        matlab.ui.control.Button
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes4_2                      matlab.ui.control.UIAxes
        UIAxes4_3                      matlab.ui.control.UIAxes
        UIAxes4_4                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        sender % 1 Hodgin 2 fitzhug 3 quasi 4 channel
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PeriodofsynapticinputPanel.Visible='Off'; 
             app.CommentsTextArea.Value='Passive signifies that the conductance of the channel does not depend on the membrane potential of the cell.Cell of radius 10 um ,Cm=1uF/cm2 and gCl=0.3 mS/cm2';
              app.ListBox_2.Position = [34 533 183 74];
              app.PeriodofinjectionmsKnob.Visible='Off';
              app.PeriodofinjectionmsKnobLabel.Visible='Off';
              app.IstimiluspAKnob.Visible='Off';
              app.IstimiluspALabel.Visible='Off';
              app.InputButtonGroup.Visible='Off';
             [IC,ICl,v,t]=bepswI(0.01,50,app.CurrentinjectionSlider.Value,app.PeriodofinjectionSlider.Value);
                
                 
                 plot(app.UIAxes ,t,v);
                 title(app.UIAxes, '');
                 xlabel(app.UIAxes,'t  (ms)','fontsize',14);
                 ylabel(app.UIAxes,'V  (mV)','fontsize',14);  
               plot(app.UIAxes_2,t,IC,'m',t,ICl,'k');
               legend(app.UIAxes_2,'I_C','I_{Cl}','location','best');
               xlabel(app.UIAxes_2,'t  (ms)','fontsize',14);
               ylabel(app.UIAxes_2,'I  (\mu A/cm^2)','fontsize',14);
               title(app.UIAxes_2, '');


               [t,V,n,m,h,INa,IK,IC2] = stEdemo(0.01,20,40);
               plot(app.UIAxes3_3,t,m,t,n,t,h,'linewidth',2);
               
               legend(app.UIAxes3_3,'n','m','h');
               xlabel(app.UIAxes3_3,'t(ms)','fontsize',14);
                ylabel(app.UIAxes3_3,'');
               title(app.UIAxes3_3, 'Gating variables');
                plot(app.UIAxes3_2,t,V,'linewidth',2);
                title(app.UIAxes3_2, 'The active potential');
                ylabel(app.UIAxes3_2,'V (mV)','fontsize',14);
                xlabel(app.UIAxes3_2,'t(ms)','fontsize',14);
               plot(app.UIAxes3,t,IC2/1000,':',t,IK/1000,'--',t,INa/1000,'linewidth',2);
             
                legend(app.UIAxes3,'I_{Cl}','I_K','I_{Na}')
                 xlabel(app.UIAxes3,'t (ms)','fontsize',14)
                 ylabel(app.UIAxes3,'mA/cm^2','fontsize',14)
                title(app.UIAxes3, 'membrane currents');
                
                    
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            switch value
                case 'Current injection'
                    
                 app.PeriodofsynapticinputPanel.Visible='Off';    
                 app.CommentsTextArea.Value='Passive signifies that the conductance of the channel does not depend on the membrane potential of the cell. Cell of radius 10 um ,Cm=1uF/cm2 and gCl=0.3 mS/cm2'; 
                 app.UIAxes_2.Visible='On';  
                 app.PeriodofinjectionSlider.Visible='On';
                 app.CurrentinjectionSlider.Visible='On'; 
                 app.PeriodofinjectionSliderLabel.Visible='On';
                 app.CurrentinjectionSliderLabel.Visible='On'; 
                  app.UIAxes_2.NextPlot = 'replace';
                   app.UIAxes.NextPlot = 'replace';
                 [IC,ICl,v,t]=bepswI(0.01,app.PeriodofinjectionSlider.Value+30,app.CurrentinjectionSlider.Value,app.PeriodofinjectionSlider.Value);
                
                 
                 plot(app.UIAxes ,t,v);
                 title(app.UIAxes, '');
                 xlabel(app.UIAxes,'t  (ms)','fontsize',14);
                 ylabel(app.UIAxes,'V  (mV)','fontsize',14);  
               plot(app.UIAxes_2,t,IC,'m',t,ICl,'k');
               legend(app.UIAxes_2,'I_C','I_{Cl}','location','best');
               xlabel(app.UIAxes_2,'t  (ms)','fontsize',14);
               ylabel(app.UIAxes_2,'I  (\mu A/cm^2)','fontsize',14);
               title(app.UIAxes_2, '');
                    
                    
                case 'Synaptic input'
                 
                 cla(app.UIAxes);
                 cla(app.UIAxes_2);
               
                 app.PeriodofsynapticinputPanel.Visible='Off'; 
                 app.Button_3.Icon='metrapsyn.PNG';
                 app.PeriodofinjectionSlider.Visible='Off';
                 app.CurrentinjectionSlider.Visible='Off';
                 app.UIAxes_2.Visible='On';  
                 app.PeriodofinjectionSliderLabel.Visible='Off';
                 app.CurrentinjectionSliderLabel.Visible='Off'; 
                 app.CommentsTextArea.Value='Ligand gated channel is one that requires the binding of a helper molecule (the ligand) to open the channel.';
                 gsyn = struct('gmax', 0.2, 'taua', 2, 't1', 5, 'Vsyn', 0);
                 [t,V1,g1] = metrapsyn(0.01,35,gsyn);
                 gsyn = struct('gmax', 0.2*[1 1], 'taua', 2*[1 1], 't1', [4 5], 'Vsyn', [-68 0]);
                 [t,V2,g2] = metrapsyn(0.01,35,gsyn);
                 plot(app.UIAxes,t,g1,'k',t,g2(:,1),'m',t,g2(:,2),'m--');
                 legend(app.UIAxes,'excitatory, solo','inhibitory, paired','excitatory, paired','location','best')
                 xlabel(app.UIAxes,'t  (ms)','fontsize',14)
                  ylabel(app.UIAxes,'g_{syn}  (\muS/cm^2)','fontsize',14)
                  plot(app.UIAxes_2,t,V1,'k',t,V2,'m');
                  legend(app.UIAxes_2,'excitatory only','inhibitory and excitatory','location','best')
                  xlabel(app.UIAxes_2,'t  (ms)','fontsize',14)
                  ylabel(app.UIAxes_2,'V  (mV)','fontsize',14)

                case 'Square wave synaptic input'
                   
                   cla(app.UIAxes);
                  cla(app.UIAxes_2);
                   app.UIAxes_2.Visible='Off';
                   
                 xlabel(app.UIAxes,'')
                  ylabel(app.UIAxes,'')
                   
                   app.PeriodofsynapticinputPanel.Visible='On';  
                   app.Button_3.Icon='metrapsyn.PNG';
                   app.PeriodofinjectionSlider.Visible='Off';
                   app.CurrentinjectionSlider.Visible='Off';
                  
                   app.PeriodofinjectionSliderLabel.Visible='Off';
                   app.CurrentinjectionSliderLabel.Visible='Off'; 
                   app.PeriodofsynapticinputPanel.Position = [71 365 142 118];
                   app.CommentsTextArea.Value='gsyn=0.1 mS/cm2 , pulsewidth=1ms , v: true synaptic response , w: approximate synaptic response ';
            end

        end

        % Value changing function: PeriodofinjectionSlider
        function PeriodofinjectionSliderValueChanging(app, event)
             Period = event.Value;
             [IC,ICl,v,t]=bepswI(0.01,Period+30,app.CurrentinjectionSlider.Value,Period);
                
                   
              plot(app.UIAxes ,t,v);
                
                   
               plot(app.UIAxes_2,t,IC,'m',t,ICl,'k');
               legend(app.UIAxes_2,'I_C','I_{Cl}','location','best');
               
        end

        % Value changing function: CurrentinjectionSlider
        function CurrentinjectionSliderValueChanging(app, event)
           Current = event.Value;
              [IC,ICl,v,t]=bepswI(0.01,app.PeriodofinjectionSlider.Value+30,Current,app.PeriodofinjectionSlider.Value);
                
                   
              plot(app.UIAxes ,t,v);
                
                   
               plot(app.UIAxes_2,t,IC,'m',t,ICl,'k');
               legend(app.UIAxes_2,'I_C','I_{Cl}','location','best');
        end

        % Button pushed function: msButton
        function msButtonPushed(app, event)
              cla(app.UIAxes);
            [t,v,w,sq]=curvssyn(0.01,0.1,2,40);
              

plot(app.UIAxes,t,v,'k',t,w,'m',t,sq);


legend(app.UIAxes,'v (syn input)','w (current approx)','location','best');


xlabel(app.UIAxes,'t  (ms)','fontsize',16)
ylabel(app.UIAxes,'V(mV)','fontsize',16)

        end

        % Button pushed function: msButton_2
        function msButton_2Pushed(app, event)
             cla(app.UIAxes);
            [t,v,w,sq]=curvssyn(0.01,0.1,4,40);
            plot(app.UIAxes,t,v,'k',t,w,'m',t,sq);


legend(app.UIAxes,'v (syn input)','w (current approx)','location','best');


xlabel(app.UIAxes,'t  (ms)','fontsize',16)
ylabel(app.UIAxes,'V(mV)','fontsize',16)


        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            value = app.ListBox_2.Value;
            switch value
                case 'Hodgkin Huxley model'
             app.PeriodofinjectionmsKnob.Visible='Off';
              app.PeriodofinjectionmsKnobLabel.Visible='Off';
              app.IstimiluspAKnob.Visible='Off';
              app.IstimiluspALabel.Visible='Off';
              app.InputButtonGroup.Visible='Off';
              app.IstimiluspAKnob.Limits=[5 60];
              app.IstimiluspAKnob.Value=40 ;
              app.PeriodofinjectionmsKnob.Limits = [1 40];
                app.PeriodofinjectionmsKnob.Value=20;
              
               app.UIAxes3_3.Visible='On';
               app.UIAxes3.Visible='On';
               app.UIAxes3_2.Visible='On';
              
                app.manipulateSwitchLabel.Visible='On';
                 app.manipulateSwitch.Visible='On';
                [t,V,n,m,h,INa,IK,IC2] = stEdemo(0.01,20,40);
               plot(app.UIAxes3_3,t,m,t,n,t,h,'linewidth',2);
               
               legend(app.UIAxes3_3,'n','m','h');
               xlabel(app.UIAxes3_3,'t(ms)','fontsize',14);
                ylabel(app.UIAxes3_3,'');
               title(app.UIAxes3_3, 'Gating variables');
                plot(app.UIAxes3_2,t,V,'linewidth',2);
                title(app.UIAxes3_2, 'The active potential');
                ylabel(app.UIAxes3_2,'V (mV)','fontsize',14);
                xlabel(app.UIAxes3_2,'t(ms)','fontsize',14);
               plot(app.UIAxes3,t,IC2/1000,':',t,IK/1000,'--',t,INa/1000,'linewidth',2);
             
                legend(app.UIAxes3,'I_{Cl}','I_K','I_{Na}')
                 xlabel(app.UIAxes3,'t (ms)','fontsize',14)
                 ylabel(app.UIAxes3,'mA/cm^2','fontsize',14)
                title(app.UIAxes3, 'membrane currents');
                
             
                case 'Fitzhug model'
                    app.sender=2;
               
              app.PeriodofinjectionmsKnob.Visible='Off';
              app.PeriodofinjectionmsKnobLabel.Visible='Off';
               app.IstimiluspAKnob.Limits=[5 60];
               app.IstimiluspAKnob.Value=40 ;
              app.IstimiluspAKnob.Visible='On';
              app.IstimiluspALabel.Visible='On';
              app.InputButtonGroup.Visible='Off';
              cla( app.UIAxes3);
              cla( app.UIAxes3_3);
               app.UIAxes3_2.Visible='On';
               app.UIAxes3_3.Visible='Off';
               app.UIAxes3.Visible='Off';
                app.manipulateSwitchLabel.Visible='Off';
                 app.manipulateSwitch.Visible='Off';
                  [t,v] = stE2d(0.01,50,app.IstimiluspAKnob.Value);
                    plot(app.UIAxes3_2,t,v)
                     xlabel( app.UIAxes3_2,'t  (ms)','fontsize',14)
                    ylabel( app.UIAxes3_2,'V  (mV)','fontsize',14)
                    title(app.UIAxes3_2, '')
                case 'Quasi active model'
                    
                    app.sender=3;
                
               app.PeriodofinjectionmsKnob.Visible='On';
              app.PeriodofinjectionmsKnobLabel.Visible='On';
              app.IstimiluspAKnob.Limits=[5 60];
              app.IstimiluspAKnob.Value=40 ;
              app.IstimiluspAKnob.Visible='On';
              app.IstimiluspALabel.Visible='On';
              app.InputButtonGroup.Visible='Off';
              cla( app.UIAxes3);
              cla( app.UIAxes3_3);
               app.UIAxes3_2.Visible='On';
               app.UIAxes3.Visible='Off';
               app.UIAxes3_3.Visible='Off';
                app.manipulateSwitchLabel.Visible='Off';
                 app.manipulateSwitch.Visible='Off';
                 app.PeriodofinjectionmsKnobLabel.Text='frequency';
                 app.PeriodofinjectionmsKnob.Limits = [30 60];
                app.PeriodofinjectionmsKnob.Value=45;
                 [t,v] = stEper(0.01,50,app.IstimiluspAKnob.Value,1000/45);
                  plot(app.UIAxes3_2,t,v)
                     xlabel( app.UIAxes3_2,'t  (ms)','fontsize',14)
                    ylabel( app.UIAxes3_2,'V  (mV)','fontsize',14)
                    title(app.UIAxes3_2, '')
                case 'Transient potassium channel'
                    app.sender=4;
                    
               cla(app.UIAxes3_2);
                app.PeriodofinjectionmsKnob.Visible='Off';
              app.PeriodofinjectionmsKnobLabel.Visible='Off';
               cla( app.UIAxes3_2)
               app.UIAxes3_2.Visible='Off';
               app.UIAxes3.Visible='On';
               app.UIAxes3_3.Visible='On';
                app.manipulateSwitchLabel.Visible='Off';
                 app.manipulateSwitch.Visible='Off';
                  app.PeriodofinjectionmsKnobLabel.Text='gsyn (mS/cm2)';
                 app.PeriodofinjectionmsKnob.Limits = [0.1 1];
                app.PeriodofinjectionmsKnob.Value=0.5;
                 app.IstimiluspAKnob.Limits=[50 400];
                   app.IstimiluspAKnob.Value=300 ;
                    app.IstimiluspAKnob.Visible='On';
              app.IstimiluspALabel.Visible='On';
              app.InputButtonGroup.Visible='On';
            
                   [t,V,INa,IK,IA]=stmolidemo(.005,20,300);
                   plot(app.UIAxes3,t,V,'m')

                   xlabel(app.UIAxes3,'t  (ms)','fontsize',14)
                   ylabel(app.UIAxes3,'V  (mV)','fontsize',14)

                     plot(app.UIAxes3_3,t,IA,'k',t,IK,'m--',t,INa,'m')

                    legend(app.UIAxes3_3,'I_A','I_K','I_{Na}')

                    xlabel(app.UIAxes3_3,'t  (ms)','fontsize',14)
                    ylabel(app.UIAxes3_3,'\muA/cm^2','fontsize',14)


            end
        end

        % Value changed function: manipulateSwitch
        function manipulateSwitchValueChanged(app, event)
            value = app.manipulateSwitch.Value;
           
            switch value
                case 'On'
                 app.sender=1;  
              app.PeriodofinjectionmsKnob.Visible='On';
              app.PeriodofinjectionmsKnobLabel.Visible='On';
              app.IstimiluspAKnob.Visible='On';
              app.IstimiluspALabel.Visible='On';
              cla( app.UIAxes3);
              cla( app.UIAxes3_3);
               app.UIAxes3_2.Visible='On';
               app.UIAxes3.Visible='Off';
               app.UIAxes3_3.Visible='Off';  
               app.PeriodofinjectionmsKnobLabel.Text='Period of injection (ms)';
               app.PeriodofinjectionmsKnob.Limits = [1 40];
               app.PeriodofinjectionmsKnob.Value=20;
               app.IstimiluspAKnob.Limits=[5 60];
                   app.IstimiluspAKnob.Value=40 ;
               stim = struct('amp',4e-5,'t1',1,'t2',21,'Tfin',40);
                [t,V] = stE(0.01,stim);
                  plot( app.UIAxes3_2,t,V,'m','linewidth',2)

                    xlabel( app.UIAxes3_2,'t  (ms)','fontsize',14)
                    ylabel( app.UIAxes3_2,'V  (mV)','fontsize',14)
                    title(app.UIAxes3_2, '')


                case 'Off'
              app.PeriodofinjectionmsKnob.Visible='Off';
              app.PeriodofinjectionmsKnobLabel.Visible='Off';
              app.IstimiluspAKnob.Visible='Off';
              app.IstimiluspALabel.Visible='Off';
              app.InputButtonGroup.Visible='Off';
             
               app.UIAxes3_2.Visible='On';
               app.UIAxes3.Visible='On';
               app.UIAxes3_3.Visible='On';
             [t,V,n,m,h,INa,IK,IC2] = stEdemo(0.01,20,40);
               plot(app.UIAxes3_3,t,m,t,n,t,h,'linewidth',2);
               
               legend(app.UIAxes3_3,'n','m','h');
               xlabel(app.UIAxes3_3,'t(ms)','fontsize',14);
                ylabel(app.UIAxes3_3,'');
               title(app.UIAxes3_3, 'Gating variables');
                plot(app.UIAxes3_2,t,V,'linewidth',2);
                title(app.UIAxes3_2, 'The active potential');
                ylabel(app.UIAxes3_2,'V (mV)','fontsize',14);
                xlabel(app.UIAxes3_2,'t(ms)','fontsize',14);
               plot(app.UIAxes3,t,IC2/1000,':',t,IK/1000,'--',t,INa/1000,'linewidth',2);
             
                legend(app.UIAxes3,'I_{Cl}','I_K','I_{Na}')
                 xlabel(app.UIAxes3,'t (ms)','fontsize',14)
                 ylabel(app.UIAxes3,'mA/cm^2','fontsize',14)
                title(app.UIAxes3, 'membrane currents');
               
               
            end
        end

        % Value changing function: PeriodofinjectionmsKnob
        function PeriodofinjectionmsKnobValueChanging(app, event)
            changingValue = event.Value;
            switch app.sender
                case 1
                 stim = struct('amp', app.IstimiluspAKnob.Value*1e-6,'t1',1,'t2',1+changingValue,'Tfin',40);
                [t,V] = stE(0.01,stim);
                  plot( app.UIAxes3_2,t,V,'m','linewidth',2)

                    xlabel( app.UIAxes3_2,'t  (ms)','fontsize',14)
                    ylabel( app.UIAxes3_2,'V  (mV)','fontsize',14)
                    title(app.UIAxes3_2, '')
   
                case 3
                     [t,v] = stEper(0.01,50,app.IstimiluspAKnob.Value,1000/changingValue);
                  plot(app.UIAxes3_2,t,v)
                     xlabel( app.UIAxes3_2,'t  (ms)','fontsize',14)
                    ylabel( app.UIAxes3_2,'V  (mV)','fontsize',14)
                    title(app.UIAxes3_2, '')
                case 4
                  
             [t,v] = stmolisyn(.005,50,changingValue,5);
              plot(app.UIAxes3_2,t,v,'m')

                   xlabel(app.UIAxes3_2,'t  (ms)','fontsize',14)
                   ylabel(app.UIAxes3_2,'V  (mV)','fontsize',14)
                    title(app.UIAxes3_2, '')
                   
            end
        end

        % Value changing function: IstimiluspAKnob
        function IstimiluspAKnobValueChanging(app, event)
            changingValue = event.Value;
            switch app.sender
                case 1
                    stim = struct('amp',changingValue*1e-6,'t1',1,'t2',1+app.PeriodofinjectionmsKnob.Value,'Tfin',40);
                [t,V] = stE(0.01,stim);
                  plot( app.UIAxes3_2,t,V,'m','linewidth',2)

                    xlabel( app.UIAxes3_2,'t  (ms)','fontsize',14)
                    ylabel( app.UIAxes3_2,'V  (mV)','fontsize',14)
                    title(app.UIAxes3_2, '')

                case 2
                     [t,v] = stE2d(0.01,50,changingValue);
                     plot(app.UIAxes3_2,t,v)
                      xlabel( app.UIAxes3_2,'t  (ms)','fontsize',14)
                    ylabel( app.UIAxes3_2,'V  (mV)','fontsize',14)
                    title(app.UIAxes3_2, '')


                case 3
                     [t,v] = stEper(0.01,50,changingValue,1000/(app.PeriodofinjectionmsKnob.Value));
                  plot(app.UIAxes3_2,t,v)
                     xlabel( app.UIAxes3_2,'t  (ms)','fontsize',14)
                    ylabel( app.UIAxes3_2,'V  (mV)','fontsize',14)
                    title(app.UIAxes3_2, '')
                case 4 
                   
                         [t,V,INa,IK,IA]=stmolidemo(.005,20,changingValue);
                   plot(app.UIAxes3,t,V,'m')


                      title(app.UIAxes3, '')

                    xlabel(app.UIAxes3,'t  (ms)','fontsize',14)
                   ylabel(app.UIAxes3,'V  (mV)','fontsize',14)
                   plot(app.UIAxes3_3,t,IA,'k',t,IK,'m--',t,INa,'m')
                    legend(app.UIAxes3_3,'I_A','I_K','I_{Na}');
                     title(app.UIAxes3_3, '')
                       
                    
            end
        end

        % Selection changed function: InputButtonGroup
        function InputButtonGroupSelectionChanged(app, event)
            selectedButton = app.InputButtonGroup.SelectedObject;
            switch selectedButton 
                case app.CurrentinjectionButton
                    cla(app.UIAxes3_2)
                 app.UIAxes3_2.Visible='Off';
               app.UIAxes3.Visible='On';
               app.UIAxes3_3.Visible='On';
                app.PeriodofinjectionmsKnob.Visible='Off';
              app.PeriodofinjectionmsKnobLabel.Visible='Off';
              app.IstimiluspAKnob.Visible='On';
              app.IstimiluspALabel.Visible='On';
              
               [t,V,INa,IK,IA]=stmolidemo(.005,20,300);
                   plot(app.UIAxes3,t,V,'m')
                    title(app.UIAxes3, '')
                   xlabel(app.UIAxes3,'t  (ms)','fontsize',14)
                   ylabel(app.UIAxes3,'V  (mV)','fontsize',14)
                   plot(app.UIAxes3_3,t,IA,'k',t,IK,'m--',t,INa,'m')
                    
                case app.SynapseButton
                  cla(app.UIAxes3_3) 
                   cla(app.UIAxes3)
               app.UIAxes3_2.Visible='On';
               app.UIAxes3.Visible='Off';
               app.UIAxes3_3.Visible='Off';
                app.PeriodofinjectionmsKnob.Visible='On';
              app.PeriodofinjectionmsKnobLabel.Visible='On';
              app.IstimiluspAKnob.Visible='Off';
              app.IstimiluspALabel.Visible='Off';
            
             [t,v] = stmolisyn(.005,50,app.PeriodofinjectionmsKnob.Value,5);
              plot(app.UIAxes3_2,t,v,'m')

                   xlabel(app.UIAxes3_2,'t  (ms)','fontsize',14)
                   ylabel(app.UIAxes3_2,'V  (mV)','fontsize',14)
                    title(app.UIAxes3_2, '')

            end
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
           cla(app.UIAxes4);
           cla(app.UIAxes4_2);
           cla(app.UIAxes4_3);
           cla(app.UIAxes4_4);
            app.UIAxes4.Visible='On';
             app.UIAxes4_2.Visible='On';
             app.UIAxes4_3.Visible='On';
             app.UIAxes4_4.Visible='On';
            
            
            [t,gE1,V1,gE2,V2]=twocell(0.01,50,5);
            curve1=animatedline(app.UIAxes4,'Color','m','LineWidth',2);
            set(gca,'xtick',[],'tickdir','out')
           ylabel(app.UIAxes4,'g_{E,1}','fontsize',14)
           xlabel(app.UIAxes4,'')
           curve2=animatedline(app.UIAxes4_2,'Color','g','LineWidth',2);
           set(gca,'xtick',[],'tickdir','out')
            ylabel(app.UIAxes4_2,'V_1','fontsize',14)
             xlabel(app.UIAxes4_2,'')
            curve3=animatedline(app.UIAxes4_3,'Color','m','LineWidth',2);
            ylabel(app.UIAxes4_3,'g_{E,2}','fontsize',14)
             xlabel(app.UIAxes4_3,'')
           set(gca,'xtick',[],'tickdir','out')
           curve4=animatedline(app.UIAxes4_4,'Color','g','LineWidth',2);
           set(gca,'tickdir','out')
xlabel(app.UIAxes4_4,'t (ms)','fontsize',14)
ylabel(app.UIAxes4_4,'V_2','fontsize',14)
for i=1:length(t)
addpoints(curve1,t(i),gE1(i));
addpoints(curve2,t(i),V1(i));
addpoints(curve3,t(i),gE2(i));
addpoints(curve4,t(i),V2(i));
drawnow limitrate

end
        end

        % Value changing function: currentSlider
        function currentSliderValueChanging(app, event)
        IsA= event.Value;
         cla(app.UIAxes4);
           cla(app.UIAxes4_2);
           cla(app.UIAxes4_3);
           cla(app.UIAxes4_4);
            app.UIAxes4.Visible='Off';
             app.UIAxes4_2.Visible='Off';
             app.UIAxes4_3.Visible='Off';
              
             app.UIAxes4_4.Visible='On';
         [t,V]=ml2(0.001,8, IsA) ;

curve1=animatedline(app.UIAxes4_4,'Color','m','LineWidth',2);
curve2=animatedline(app.UIAxes4_4,'Color','m','LineWidth',2);
curve3=animatedline(app.UIAxes4_4,'Color','g','LineWidth',0.5,'Marker','+');
curve4=animatedline(app.UIAxes4_4,'Color','g','LineWidth',0.5,'Marker','+');
app.UIAxes4_4.Position = [394 15 500 300];
for i=1:length(t)
addpoints(curve1,t(i),V(i,1));

addpoints(curve2,t(i),V(i,2)-140);
addpoints(curve3,t(i),0);
addpoints(curve4,t(i),-140);
drawnow limitrate

end
text(app.UIAxes4_4,7,20,'V_1')
text(app.UIAxes4_4,7,20-180,'V_2')
set(gca,'tickdir','out')
            
        end

        % Button pushed function: cellburstfractionButton
        function cellburstfractionButtonPushed(app, event)
             cla(app.UIAxes4);
           cla(app.UIAxes4_2);
           cla(app.UIAxes4_3);
           cla(app.UIAxes4_4);
            app.UIAxes4.Visible='Off';
             app.UIAxes4_2.Visible='Off';
             app.UIAxes4_3.Visible='Off';
              
             app.UIAxes4_4.Visible='On';
              [t,spk]= hyEprnet(.01,2000,100,.2,0.0045,0.007,2000);
             app.UIAxes4_4.Position = [394 15 500 300];
             %curve=animatedline(app.UIAxes4_4,'Color','k','LineWidth',1);
%for i=1:length(t)
%addpoints(curve,t(i),spk(i)/100);
%drawnow limitrate
%end
%ylim(app.UIAxes4_4,[-.1 1.1])

%set(gca,'tickdir','out'
plot(app.UIAxes4_4,t,spk/100,'LineWidth',1);
ylim(app.UIAxes4_4,[-.1 1.1]);
xlabel(app.UIAxes4_4,'t  (ms)','fontsize',14)
ylabel(app.UIAxes4_4,'Burst Fraction','fontsize',14)

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NeuronSimulatorUIFigure = uifigure;
            app.NeuronSimulatorUIFigure.Name = 'Neuron Simulator';
            app.ElectrotonicofnervecellsLabel = uilabel(app.NeuronSimulatorUIFigure);
            app.ElectrotonicofnervecellsLabel.Text = 'Electrotonic of nerve cells';
            app.TabGroup = uitabgroup(app.NeuronSimulatorUIFigure);
            app.ThepassiveisopotentialcellTab = uitab(app.TabGroup);
            app.ThepassiveisopotentialcellTab.Title = 'The passive isopotential cell';
            app.ListBox = uilistbox(app.ThepassiveisopotentialcellTab);
            app.ListBox.Items = {'Current injection', 'Synaptic input', 'Square wave synaptic input'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Current injection';
            app.Button = uibutton(app.ThepassiveisopotentialcellTab, 'push');
            app.Button.Icon = 'violet.jpg';
            app.Button.Text = '';
            app.UIAxes = uiaxes(app.ThepassiveisopotentialcellTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.ZTick = [0 0.5 1];
            app.CommentsTextAreaLabel = uilabel(app.ThepassiveisopotentialcellTab);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.CommentsTextArea = uitextarea(app.ThepassiveisopotentialcellTab);
            app.CommentsTextArea.Editable = 'off';
            app.PeriodofsynapticinputPanel = uipanel(app.ThepassiveisopotentialcellTab);
            app.PeriodofsynapticinputPanel.Title = 'Period of synaptic input';
            app.msButton = uibutton(app.PeriodofsynapticinputPanel, 'push');
            app.msButton.ButtonPushedFcn = createCallbackFcn(app, @msButtonPushed, true);
            app.msButton.Text = '2 ms';
            app.msButton_2 = uibutton(app.PeriodofsynapticinputPanel, 'push');
            app.msButton_2.ButtonPushedFcn = createCallbackFcn(app, @msButton_2Pushed, true);
            app.msButton_2.Text = '4 ms';
            app.PeriodofinjectionSliderLabel = uilabel(app.ThepassiveisopotentialcellTab);
            app.PeriodofinjectionSliderLabel.Text = 'Period of injection';
            app.PeriodofinjectionSlider = uislider(app.ThepassiveisopotentialcellTab);
            app.PeriodofinjectionSlider.Limits = [5 50];
            app.PeriodofinjectionSlider.ValueChangingFcn = createCallbackFcn(app, @PeriodofinjectionSliderValueChanging, true);
            app.PeriodofinjectionSlider.Value = 20;
            app.CurrentinjectionSliderLabel = uilabel(app.ThepassiveisopotentialcellTab);
            app.CurrentinjectionSliderLabel.Text = 'Current injection';
            app.CurrentinjectionSlider = uislider(app.ThepassiveisopotentialcellTab);
            app.CurrentinjectionSlider.Limits = [5 50];
            app.CurrentinjectionSlider.ValueChangingFcn = createCallbackFcn(app, @CurrentinjectionSliderValueChanging, true);
            app.CurrentinjectionSlider.Value = 10;
            app.UIAxes_2 = uiaxes(app.ThepassiveisopotentialcellTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_2.XLim = [0 1];
            app.UIAxes_2.YLim = [0 1];
            app.UIAxes_2.ZLim = [0 1];
            app.UIAxes_2.CLim = [0 1];
            app.UIAxes_2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_2.ZTick = [0 0.5 1];
            app.Button_3 = uibutton(app.ThepassiveisopotentialcellTab, 'push');
            app.Button_3.Icon = 'bepswID.PNG';
            app.Button_3.Text = '';
            app.TheactiveisopotentialcellTab = uitab(app.TabGroup);
            app.TheactiveisopotentialcellTab.Title = 'The active isopotential cell';
            app.ListBox_2 = uilistbox(app.TheactiveisopotentialcellTab);
            app.ListBox_2.Items = {'Hodgkin Huxley model', 'Fitzhug model', 'Quasi active model', 'Transient potassium channel'};
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Value = 'Hodgkin Huxley model';
            app.Button_4 = uibutton(app.TheactiveisopotentialcellTab, 'push');
            app.Button_4.Icon = 'nerf.png';
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.TheactiveisopotentialcellTab, 'push');
            app.Button_5.Icon = 'synapse.jpg';
            app.Button_5.Text = '';
            app.PeriodofinjectionmsKnobLabel = uilabel(app.TheactiveisopotentialcellTab);
            app.PeriodofinjectionmsKnobLabel.Text = 'Period of injection (ms)';
            app.PeriodofinjectionmsKnob = uiknob(app.TheactiveisopotentialcellTab, 'continuous');
            app.PeriodofinjectionmsKnob.Limits = [1 40];
            app.PeriodofinjectionmsKnob.ValueChangingFcn = createCallbackFcn(app, @PeriodofinjectionmsKnobValueChanging, true);
            app.PeriodofinjectionmsKnob.Value = 2;
            app.IstimiluspALabel = uilabel(app.TheactiveisopotentialcellTab);
            app.IstimiluspALabel.Text = 'I stimilus (pA)';
            app.IstimiluspAKnob = uiknob(app.TheactiveisopotentialcellTab, 'continuous');
            app.IstimiluspAKnob.Limits = [5 60];
            app.IstimiluspAKnob.ValueChangingFcn = createCallbackFcn(app, @IstimiluspAKnobValueChanging, true);
            app.IstimiluspAKnob.Value = 40;
            app.InputButtonGroup = uibuttongroup(app.TheactiveisopotentialcellTab);
            app.InputButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputButtonGroupSelectionChanged, true);
            app.InputButtonGroup.Title = 'Input';
            app.CurrentinjectionButton = uiradiobutton(app.InputButtonGroup);
            app.CurrentinjectionButton.Text = 'Current injection';
            app.CurrentinjectionButton.Value = true;
            app.SynapseButton = uiradiobutton(app.InputButtonGroup);
            app.SynapseButton.Text = ' Synapse';
            app.manipulateSwitchLabel = uilabel(app.TheactiveisopotentialcellTab);
            app.manipulateSwitchLabel.Text = 'manipulate';
            app.manipulateSwitch = uiswitch(app.TheactiveisopotentialcellTab, 'slider');
            app.manipulateSwitch.ValueChangedFcn = createCallbackFcn(app, @manipulateSwitchValueChanged, true);
            app.UIAxes3 = uiaxes(app.TheactiveisopotentialcellTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes3_2 = uiaxes(app.TheactiveisopotentialcellTab);
            title(app.UIAxes3_2, 'Title')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            app.UIAxes3_3 = uiaxes(app.TheactiveisopotentialcellTab);
            title(app.UIAxes3_3, 'Title')
            xlabel(app.UIAxes3_3, 'X')
            ylabel(app.UIAxes3_3, 'Y')
            app.NeuronalnetworksTab = uitab(app.TabGroup);
            app.NeuronalnetworksTab.Title = 'Neuronal networks';
            app.Button_6 = uibutton(app.NeuronalnetworksTab, 'push');
            app.Button_6.Icon = 'neural networking.jpg';
            app.Button_6.Text = '';
            app.SimpletwocellnetworkLabel = uilabel(app.NeuronalnetworksTab);
            app.SimpletwocellnetworkLabel.Text = 'Simple two-cell network';
            app.LeakyintegrateandfirecellmodelLabel = uilabel(app.NeuronalnetworksTab);
            app.LeakyintegrateandfirecellmodelLabel.Text = 'Leaky integrate and fire cell model';
            app.Button_7 = uibutton(app.NeuronalnetworksTab, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Icon = 'neurons.PNG';
            app.Button_7.Text = '';
            app.SimplethreecellnetworkLabel = uilabel(app.NeuronalnetworksTab);
            app.SimplethreecellnetworkLabel.Text = 'Simple three-cell network';
            app.PeriodButtonGroup = uibuttongroup(app.NeuronalnetworksTab);
            app.PeriodButtonGroup.Title = 'Period';
            app.msButton_3 = uiradiobutton(app.PeriodButtonGroup);
            app.msButton_3.Text = '2 ms';
            app.msButton_3.Value = true;
            app.msButton_4 = uiradiobutton(app.PeriodButtonGroup);
            app.msButton_4.Text = '5 ms';
            app.HodgkinHuxleybasednetworksLabel = uilabel(app.NeuronalnetworksTab);
            app.HodgkinHuxleybasednetworksLabel.Text = 'Hodgkin Huxley based networks';
            app.currentSliderLabel = uilabel(app.NeuronalnetworksTab);
            app.currentSliderLabel.Text = 'current';
            app.currentSlider = uislider(app.NeuronalnetworksTab);
            app.currentSlider.Limits = [500 3100];
            app.currentSlider.ValueChangingFcn = createCallbackFcn(app, @currentSliderValueChanging, true);
            app.currentSlider.Value = 1000;
            app.networkofPinskyRinzelcellsLabel = uilabel(app.NeuronalnetworksTab);
            app.networkofPinskyRinzelcellsLabel.Text = 'network of Pinsky–Rinzel cells';
            app.cellburstfractionButton = uibutton(app.NeuronalnetworksTab, 'push');
            app.cellburstfractionButton.ButtonPushedFcn = createCallbackFcn(app, @cellburstfractionButtonPushed, true);
            app.cellburstfractionButton.Text = '100-cell burst fraction';
            app.UIAxes4 = uiaxes(app.NeuronalnetworksTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes4_2 = uiaxes(app.NeuronalnetworksTab);
            title(app.UIAxes4_2, 'Title')
            xlabel(app.UIAxes4_2, 'X')
            ylabel(app.UIAxes4_2, 'Y')
            app.UIAxes4_3 = uiaxes(app.NeuronalnetworksTab);
            title(app.UIAxes4_3, 'Title')
            xlabel(app.UIAxes4_3, 'X')
            ylabel(app.UIAxes4_3, 'Y')
            app.UIAxes4_4 = uiaxes(app.NeuronalnetworksTab);
            title(app.UIAxes4_4, 'Title')
            xlabel(app.UIAxes4_4, 'X')
            ylabel(app.UIAxes4_4, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = num_an

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NeuronSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NeuronSimulatorUIFigure)
        end
    end
end